classdef WildLeap3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WildLeap3DUIFigure         matlab.ui.Figure
        CalcSumm                   matlab.ui.control.Button
        Species                    matlab.ui.control.DropDown
        SpeciesDropDownLabel       matlab.ui.control.Label
        FrmDisp                    matlab.ui.control.EditField
        CurrframeEditFieldLabel    matlab.ui.control.Label
        AngleDisp                  matlab.ui.control.DropDown
        PositiondataLabel_4        matlab.ui.control.Label
        AccelDisp                  matlab.ui.control.DropDown
        PositiondataLabel_3        matlab.ui.control.Label
        VelDisp                    matlab.ui.control.DropDown
        PositiondataLabel_2        matlab.ui.control.Label
        PosDisp                    matlab.ui.control.DropDown
        PositiondataLabel          matlab.ui.control.Label
        TreeSpecies                matlab.ui.control.EditField
        TreeSpeciesEditFieldLabel  matlab.ui.control.Label
        SbstHght                   matlab.ui.control.NumericEditField
        SbstOrientdegLabel_3       matlab.ui.control.Label
        SbstComp                   matlab.ui.control.NumericEditField
        SbstOrientdegLabel_2       matlab.ui.control.Label
        SbstDiam                   matlab.ui.control.NumericEditField
        SbstDiamcmEditFieldLabel   matlab.ui.control.Label
        SbstOrient                 matlab.ui.control.NumericEditField
        SbstOrientdegLabel         matlab.ui.control.Label
        ExportData                 matlab.ui.control.Button
        LoadData                   matlab.ui.control.Button
        setOffset                  matlab.ui.control.NumericEditField
        CamoffsetreltoCam1EditFieldLabel  matlab.ui.control.Label
        VidNum                     matlab.ui.control.Spinner
        VidNumSpinnerLabel         matlab.ui.control.Label
        FrameSlider                matlab.ui.control.Slider
        FrameSliderLabel           matlab.ui.control.Label
        VidAxes                    matlab.ui.control.UIAxes
        DataPlot                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        projData=[]; % structure storing the data exported from DLTdv8a
        projPname=""; % directory with DLT data (useful for saving exported data)
        nVids=[]; % number of videos in the dataset
        VidObj={} % Cell array to store video objects
        currFrame=1; % Current video frame
        nFrames=[]; % Number of frames in video
        offset={}; % Video offsets (relative to video 1)
        framerate=[]; % Video framerate
        time=[]; % time vector for video and data
        currVideo=1; % Current video number
        leapEvents={}; % Leaping events (take-off, start of landing, start of push-off, end of landing)
        kmtcs=struct('time',[], ... % Large pre-allocated structure to hold kinematics variables
            'launch',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
            'land',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
            'CoM',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
            'angles',struct('attack',[],'abduction',[],'tailMid',[],'tailOverall',[]));
        kmtcs2D=struct('time',[],'CoM2d',[],'CoM2dvel',[],'CoM2dacc',[],'launch2d',[],'land2d',[]);
        firstFrame=1; % First frame in which we have digitized data
        lastFrame=1; % Last frame in which we have digitized data
        exportVars=struct('Species','','BodyMass',NaN,'BodyWght',NaN,...
            'toVel',NaN,'toAng',NaN,'toMnAccel',NaN,'toMnForce',NaN,...
            'toMnForceBW',NaN,'toMaxAccel',NaN,'toMaxForce',NaN,...
            'toMaxForceBW',NaN,'maxHght',NaN,'netHght',NaN,'totalDist',NaN,'slDist',...
            NaN,'MnLeapVel',NaN,'ldVel',NaN,'ldMnAccel',NaN,'ldMnForce',...
            NaN,'ldMnForceBW',NaN,'ldMaxAccel',NaN,'ldMaxForce',NaN,...
            'ldMaxForceBW',NaN,'launchBranchDisp',NaN,'landBranchDisp',...
            NaN,'MinAngAttack',NaN,'MeanAngAttack',NaN,'MaxAngAttack',...
            NaN,'MinTailTrunk',NaN,'MeanTailTrunk',NaN,'MaxTailTrunk',...
            NaN,'MinTailMid',NaN,'MeanTailMid',NaN,'MaxTailMid',NaN);
        metadata=struct('SbstDiam',NaN,'SbstOrient',NaN,'SbstComp',NaN,...
            'SbstHght',NaN,'TreeSpecies',"");
        dataLoaded=0;
    end

    
    methods (Access = private)
        
        function []=PaintFrame(app)
            % Show the video frame in the video axes
            imshow(read(app.VidObj{app.currVideo},...
                app.currFrame+str2double(app.offset(app.currVideo))), ...
                'Parent',app.VidAxes);
        end
    end

    methods (Static)
        function ANG = PlanarAngle(v1,v0,unit)
            %%ANGLES Returns the absolute angle between two vectors (2D or 3D).
            %
            % SYNTAX
            % ANG=angles(v1,v0,
            % ANG=angles(v1,v0,unit)
            %
            % Returns the absolute angle of a given vector (v1) from a reference vector (v0),
            % where the vector matrices are as follows: v1=(x1,y1,z1) and v0=(x0,y0,z0).
            % 
            % If v1 and v0 are matrices of the same length, ANG is a matrix of equal
            % length, where ANG(n) is the angle between v1(n) and v0(n).
            %
            % If v0 is a single vector, ANG is a matrix equal to the length of v1,
            % where ANG(n) is the angle between v1(n) and v0.
            %
            % If unit='deg' or is undefined, angles are given in degrees, 0<=ANG<=180.
            % If unit='rad', angles are given in radians, 0<=ANG<=pi.
            %
            % RELATED FUNCTIONS: ANGLES2D, ANGLES3D.
            %
            % --------------------
            % Author: Brad A. Chadwell
            % e-mail: chadba1@wfu.edu
            % Created: March 2007 while attending Wake Forest Univ.
            % Modified: November 2023 by Jesse W. Young to make use of
            % MATLAB's vecnorm function
            
            if nargin<3, unit='deg'; end
            
            [m,n]=size(v1); [i,j]=size(v0);
            
            if n~=j, error('Vector Dimensions Do Not Match'), end 
            
            if i==1 && m~=1
               v0=repmat(v0,m,1);
            elseif  i>1 && i~=m
               error('Vector Matrices Are Not The Same Lengths') 
            end
            
            a=dot(v1,v0,2); b=vecnorm(v1,2,2).*vecnorm(v0,2,2);
            b(b==0)=nan;
            
            c=roundn(a./b,-14);
            
            switch lower(unit)
                case 'deg'
                   ANG=acosd(c); 
                       
                case 'rad'
                   ANG=acos(c);
            end
        end

        function []=plotResults(time,vars,axes,TO,LD,varname,ylab)
            if iscell(vars) 
                vars=reshape(cell2mat(vars),size(time,1),[]); 
            end 
            cla(axes); axes.View=[0 90];
            set(axes,"Units","pixels","XGrid","on","YGrid","on");
            plot(time,vars,'LineWidth',1.5,'Parent',axes);
            xline(axes,time(TO),'--k','Take-off');
            xline(axes,time(LD),'--k','Landing');
            xlabel(axes,'Time (sec)'); ylabel(axes,ylab);
            axes.Title.String=varname;
            axes.Title.Color="#4169E1";

            if size(vars,2)==3
                legend(axes,'X','Y','Z','Location','southeast',...
                    'AutoUpdate','off');
            else
                legend(axes,'off')
            end
        end

        function [projected, Pt] = projectToPlane(xyz,startxyz,endxyz)
            % modified from R code written by Cliodhna Quigley and
            % published with:
            % Janisch J, Perinot E, Fusani L, and Quigley C. 2021. 
            % Deciphering choreographies of elaborate courtship displays 
            % of golden‐collared manakins using markerless motion capture. 
            % Ethology 127(7):550-562.

            % take a matrix of xyz points (each column is 1 point) and project them onto a 2d plane
            % calculated from start and end points, and orthogonal to z axis
            se_diff = endxyz - startxyz;
            
            % gram-schmidt method: v2 = x2 - (x2.v1/v1.v1)v1
            v1=[0,0,1]; % first vector is z unit vector
            v2=se_diff - ((sum(se_diff.*v1)/sum(v1.*v1)).*v1); % this just eliminates the z part in order to orthogonalize this dimension
            
            % and make them orthonormal by normalising:
            u1 = v1; % I made it a unit vector from the beginning
            u2 = v2 / (sqrt(sum(v2.*v2))); % divide it by its norm
            
            % now make it into a projection matrix (already transposed for the upcoming multiplication)
            Pt=[u2;u1]; % u2 goes first because it's the horizontal component
            
            % and project it:
            projected=(Pt*xyz')'; % 2x3 proj mat times 3xn coordinate mat
        end

        function [arclen,seglen] = arclength(px,py,varargin)
            % arclength: compute arc length of a space curve, or any curve represented as a sequence of points
            % usage: [arclen,seglen] = arclength(px,py)         % a 2-d curve
            % usage: [arclen,seglen] = arclength(px,py,pz)      % a 3-d space curve
            % usage: [arclen,seglen] = arclength(px,py,method)  % specifies the method used
            %
            % Computes the arc length of a function or any
            % general 2-d, 3-d or higher dimensional space
            % curve using various methods.
            %
            % arguments: (input)
            %  px, py, pz, ... - vectors of length n, defining points
            %        along the curve. n must be at least 2. Replicate
            %        points should not be present in the curve.
            %
            %  method - (OPTIONAL) string flag - denotes the method
            %        used to compute the arc length of the curve.
            %
            %        method may be any of 'linear', 'spline', or 'pchip',
            %        or any simple contraction thereof, such as 'lin',
            %        'sp', or even 'p'.
            %        
            %        method == 'linear' --> Uses a linear chordal
            %               approximation to compute the arc length.
            %               This method is the most efficient.
            %
            %        method == 'pchip' --> Fits a parametric pchip
            %               approximation, then integrates the
            %               segments numerically.
            %
            %        method == 'spline' --> Uses a parametric spline
            %               approximation to fit the curves, then
            %               integrates the segments numerically.
            %               Generally for a smooth curve, this
            %               method may be most accurate.
            %
            %        DEFAULT: 'linear'
            %
            %
            % arguments: (output)
            %  arclen - scalar total arclength of all curve segments
            %
            %  seglen - arclength of each independent curve segment
            %           there will be n-1 segments for which the
            %           arc length will be computed.
            %
            %
            % Example:
            % % Compute the length of the perimeter of a unit circle
            % theta = linspace(0,2*pi,10);
            % x = cos(theta);
            % y = sin(theta);
            %
            % % The exact value is
            % 2*pi
            % % ans =
            % %          6.28318530717959
            %
            % % linear chord lengths
            % arclen = arclength(x,y,'l')
            % % arclen =
            % %           6.1564
            %
            % % Integrated pchip curve fit
            % arclen = arclength(x,y,'p')
            % % arclen =
            % %          6.2782
            %
            % % Integrated spline fit
            % arclen = arclength(x,y,'s')
            % % arclen =
            % %           6.2856
            %
            % Example:
            % % A (linear) space curve in 5 dimensions
            % x = 0:.25:1;
            % y = x;
            % z = x;
            % u = x;
            % v = x;
            %
            % % The length of this curve is simply sqrt(5)
            % % since the "curve" is merely the diagonal of a
            % % unit 5 dimensional hyper-cube.
            % [arclen,seglen] = arclength(x,y,z,u,v,'l')
            % % arclen =
            % %           2.23606797749979
            % % seglen =
            % %         0.559016994374947
            % %         0.559016994374947
            % %         0.559016994374947
            % %         0.559016994374947
            %
            %
            % See also: interparc, spline, pchip, interp1
            %
            % Author: John D'Errico
            % e-mail: woodchips@rochester.rr.com
            % Release: 1.0
            % Release date: 3/10/2010
            
            % unpack the arguments and check for errors
            if nargin < 2
              error('ARCLENGTH:insufficientarguments', ...
                'at least px and py must be supplied')
            end
            
            n = length(px);
            % are px and py both vectors of the same length?
            if ~isvector(px) || ~isvector(py) || (length(py) ~= n)
              error('ARCLENGTH:improperpxorpy', ...
                'px and py must be vectors of the same length')
            elseif n < 2
              error('ARCLENGTH:improperpxorpy', ...
                'px and py must be vectors of length at least 2')
            end
            
            % compile the curve into one array
            data = [px(:),py(:)];
            
            % defaults for method and tol
            method = 'linear';
            
            % which other arguments are included in varargin?
            if numel(varargin) > 0
              % at least one other argument was supplied
              for i = 1:numel(varargin)
                arg = varargin{i};
                if ischar(arg)
                  % it must be the method
                  validmethods = {'linear' 'pchip' 'spline'};
                  ind = strmatch(lower(arg),validmethods);
                  if isempty(ind) || (length(ind) > 1)
                    error('ARCLENGTH:invalidmethod', ...
                      'Invalid method indicated. Only ''linear'',''pchip'',''spline'' allowed.')
                  end
                  method = validmethods{ind};
                  
                else
                  % it must be pz, defining a space curve in higher dimensions
                  if numel(arg) ~= n
                    error('ARCLENGTH:inconsistentpz', ...
                      'pz was supplied, but is inconsistent in size with px and py')
                  end
                  
                  % expand the data array to be a 3-d space curve
                  data = [data,arg(:)]; %#ok
                end
              end
              
            end
            
            % what dimension do we live in?
            nd = size(data,2);
            
            % compute the chordal linear arclengths
            seglen = sqrt(sum(diff(data,[],1).^2,2));
            arclen = sum(seglen);
            
            % we can quit if the method was 'linear'.
            if strcmpi(method,'linear')
              % we are now done. just exit
              return
            end
            
            % 'spline' or 'pchip' must have been indicated,
            % so we will be doing an integration. Save the
            % linear chord lengths for later use.
            chordlen = seglen;
            
            % compute the splines
            spl = cell(1,nd);
            spld = spl;
            diffarray = [3 0 0;0 2 0;0 0 1;0 0 0];
            for i = 1:nd
              switch method
                case 'pchip'
                  spl{i} = pchip([0;cumsum(chordlen)],data(:,i));
                case 'spline'
                  spl{i} = spline([0;cumsum(chordlen)],data(:,i));
                  nc = numel(spl{i}.coefs);
                  if nc < 4
                    % just pretend it has cubic segments
                    spl{i}.coefs = [zeros(1,4-nc),spl{i}.coefs];
                    spl{i}.order = 4;
                  end
              end
              
              % and now differentiate them
              xp = spl{i};
              xp.coefs = xp.coefs*diffarray;
              xp.order = 3;
              spld{i} = xp;
            end
            
            % numerical integration along the curve
            polyarray = zeros(nd,3);
            for i = 1:spl{1}.pieces
              % extract polynomials for the derivatives
              for j = 1:nd
                polyarray(j,:) = spld{j}.coefs(i,:);
              end
              
              % integrate the arclength for the i'th segment
              % using quadgk for the integral. I could have
              % done this part with an ode solver too.
              seglen(i) = quadgk(@(t) segkernel(t),0,chordlen(i));
            end
            
            % and sum the segments
            arclen = sum(seglen);

            % ==========================
            %   end main function
            % ==========================
            %   begin nested functions
            % ==========================
              function val = segkernel(t)
                % sqrt((dx/dt)^2 + (dy/dt)^2)
                
                val = zeros(size(t));
                for k = 1:nd
                  val = val + polyval(polyarray(k,:),t).^2;
                end
                val = sqrt(val);
                
              end % function segkernel
        end % function arclength
            
        function [uv] = dlt_inverse(c,xyz)

                % function [uv] = dlt_inverse(c,xyz)
                %
                % This function reconstructs the pixel coordinates of a 3D coordinate as
                % seen by the camera specificed by DLT coefficients c
                %
                % Inputs:
                %  c - 11 DLT coefficients for the camera, [11,1] array
                %  xyz - [x,y,z] coordinates over f frames,[f,3] array
                %
                % Outputs:
                %  uv - pixel coordinates in each frame, [f,2] array
                %
                % Ty Hedrick
                
                % write the matrix solution out longhand for Matlab vector operation over
                % all points at once
                uv(:,1)=(xyz(:,1).*c(1)+xyz(:,2).*c(2)+xyz(:,3).*c(3)+c(4))./ ...
                  (xyz(:,1).*c(9)+xyz(:,2).*c(10)+xyz(:,3).*c(11)+1);
                uv(:,2)=(xyz(:,1).*c(5)+xyz(:,2).*c(6)+xyz(:,3).*c(7)+c(8))./ ...
                  (xyz(:,1).*c(9)+xyz(:,2).*c(10)+xyz(:,3).*c(11)+1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the UI in the middle of the screen
            scsz=get(0,'screensize');
            app.WildLeap3DUIFigure.Position(1:2)=...
                (scsz(3:4)-app.WildLeap3DUIFigure.Position(3:4))/2;

            app.VidAxes.InnerPosition=app.VidAxes.Position;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            newFrame = round(app.FrameSlider.Value);
            app.currFrame=newFrame;
            app.FrmDisp.Value=num2str(newFrame);
            PaintFrame(app)
        end

        % Value changed function: FrmDisp
        function FrmDispValueChanged(app, event)

            % First, decide if value is numeric or text
            valNum=str2double(app.FrmDisp.Value);
            if ~isnan(valNum)
                [app.currFrame,app.FrameSlider.Value]=deal(round(valNum));
                app.FrmDisp.Value=num2str(round(valNum));
            else
                switch lower(app.FrmDisp.Value)
                    case {"t","to","takeoff","take-off"}
                        if ~isempty(app.leapEvents) || isempty(app.leapEvents{1}(1))
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.leapEvents{1}(1));
                            app.FrmDisp.Value=num2str(app.leapEvents{1}(1));
                        else
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.firstFrame);
                        end
                    case {"l","land","landing"}
                        if ~isempty(app.leapEvents) || isempty(app.leapEvents{2}(1))
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.leapEvents{2}(1));
                            app.FrmDisp.Value=num2str(app.leapEvents{2}(1));
                        else
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.firstFrame);
                            
                        end
                    case {"p","po","pushoff","push-off"}
                        if ~isempty(app.leapEvents) || isempty(app.leapEvents{3}(1))
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.leapEvents{3}(1));
                            app.FrmDisp.Value=num2str(app.leapEvents{3}(1));
                        else
                            [app.currFrame,app.FrameSlider.Value]=...
                                deal(app.firstFrame);
                        end   
                end
            end
            PaintFrame(app)
        end

        % Value changed function: setOffset
        function setOffsetValueChanged(app, event)
            value = app.setOffset.Value;
            app.offset{app.currVideo}=num2str(app.offset{app.currVideo});
        end

        % Value changed function: VidNum
        function VidNumValueChanged(app, event)
            app.currVideo=app.VidNum.Value;
            app.setOffset.Value=str2double(app.offset{app.currVideo});
            app.setOffsetValueChanged(event);
            PaintFrame(app)
        end

        % Value changed function: PosDisp
        function PosDispValueChanged(app, event)
            value = app.PosDisp.Value;
            to=app.leapEvents{1}; ld=app.leapEvents{2};

            cla(app.DataPlot,'reset')
            switch value
                case '--'
                    app.DataPlot.Title.String=...
                        "Kinematics data analyzed. Ready to display.";
                case 'Launching substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.launch.pos), ...
                        app.DataPlot,to,ld,...
                        "Launching substrate position data","Position (m)");
                case 'Landing substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.land.pos), ...
                        app.DataPlot,to,ld,...
                        "Landing substrate position data","Position (m)");
                case 'Primate CoM'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.CoM.pos), ...
                        app.DataPlot,to,ld,...
                        "Primate CoM position data","Position (m)");
                case '3D plot'
                    scatter3(app.DataPlot, ...
                            app.kmtcs.CoM.pos.X, ...
                            app.kmtcs.CoM.pos.Y, ...
                            app.kmtcs.CoM.pos.Z, ...
                            40,"filled", ...
                        "MarkerFaceColor","#D3D3D3");
                    hold(app.DataPlot,'on')
                    scatter3(app.DataPlot, ...
                        app.kmtcs.CoM.pos.X(cell2mat(app.leapEvents)), ...
                        app.kmtcs.CoM.pos.Y(cell2mat(app.leapEvents)), ...
                        app.kmtcs.CoM.pos.Z(cell2mat(app.leapEvents)), ...
                        40,"filled", ...
                        "MarkerFaceColor","red");
                    view(app.DataPlot,45,10)
                    legend(app.DataPlot,'off')
            end

            app.VelDisp.Value="--";
            app.AccelDisp.Value="--";
            app.AngleDisp.Value="--";
        end

        % Value changed function: VelDisp
        function VelDispValueChanged(app, event)
            value = app.VelDisp.Value;
            to=app.leapEvents{1}; ld=app.leapEvents{2};

            cla(app.DataPlot)
            switch value
                case '--'
                    app.DataPlot.Title.String=...
                        "Kinematics data analyzed. Ready to display.";
                case 'Launching substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.launch.vel), ...
                        app.DataPlot,to,ld,...
                        "Launching substrate velocity data","Velocity (m/sec)");
                case 'Landing substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.land.vel), ...
                        app.DataPlot,to,ld,...
                        "Landing substrate velocity data","Velocity (m/sec)");
                case 'Primate CoM'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.CoM.vel), ...
                        app.DataPlot,to,ld,...
                        "Primate CoM velocity data","Velocity (m/sec)");
            end

            app.PosDisp.Value="--";
            app.AccelDisp.Value="--";
            app.AngleDisp.Value="--";

        end

        % Value changed function: AccelDisp
        function AccelDispValueChanged(app, event)
            value = app.AccelDisp.Value;
            to=app.leapEvents{1}; ld=app.leapEvents{2};
            
            cla(app.DataPlot)
            switch value
                case '--'
                    app.DataPlot.Title.String=...
                        "Kinematics data analyzed. Ready to display.";
                case 'Launching substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.launch.accel), ...
                        app.DataPlot,to,ld,...
                        "Launching substrate acceleration data","Acceleration (m/sec^2)");
                    yline(app.DataPlot,-9.80665,'--k','Gravity');
                case 'Landing substrate'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.land.accel), ...
                        app.DataPlot,to,ld,...
                        "Landing substrate acceleration data","Acceleration (m/sec^2)");
                    yline(app.DataPlot,-9.80665,'--k','Gravity');
                case 'Primate CoM'
                    app.plotResults(app.kmtcs.time,...
                        struct2cell(app.kmtcs.CoM.accel), ...
                        app.DataPlot,to,ld,...
                        "Primate CoM acceleration data","Acceleration (m/sec^2)");
                    yline(app.DataPlot,-9.80665,'--k','Gravity');
            end

            app.PosDisp.Value="--";
            app.VelDisp.Value="--";
            app.AngleDisp.Value="--";
            
        end

        % Button down function: DataPlot
        function DataPlotButtonDown(app, event)
            xPos=app.DataPlot.CurrentPoint(1,1);
            [~,app.currFrame]=min(abs(app.kmtcs.time-xPos));
            app.FrameSlider.Value=app.currFrame;
            app.FrmDisp.Value=num2str(app.currFrame);
            xline(app.DataPlot,xPos,'--r');
            PaintFrame(app);
        end

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            if ~app.dataLoaded
                [projFname,app.projPname]=uigetfile('*.mat',...
                    'Choose DLTdv8a project file to analyze...');
                if projFname==0
                    return
                end
    
                % Load project data and save to app structure
                projectData=load(fullfile(app.projPname,projFname));
                app.projData=projectData.udExport;
    
                % Rename the figure
                trialname=strrep(projFname,'_dvProject.mat','');
                set(app.WildLeap3DUIFigure,"Name", ...
                    strcat("WildLeap3D | ",trialname));
    
                % Check to see if we can open the videos without user input
                projDir=dir(app.projPname); projFiles={projDir.name};
                movFiles=projFiles(contains(lower(projFiles),".mp4"));
                [~,movNames,~] = fileparts(movFiles);
    
                for i=1:app.projData.data.nvid
    
                    if ~isempty(movNames) % check for correct video
                        for j=1:length(movNames)
                            [~,currMov,~]=fileparts(app.projData.video.files(i));
                            movMatch=contains(currMov,movNames(j));
                            if movMatch
                                movFname=movFiles{j};
                                movPname=app.projPname;
                                break
                            end
                        end
                    else % video not in project directory
                        VidMsg=sprintf(...
                        ['There are %u videos assocuated with this project. ',...
                        'Please open Video %i: %s'],...
                        app.projData.data.nvid,i,app.projData.video.files{i});
                    uiwait(msgbox(VidMsg,sprintf("Open video %u",i),"modal"));
                    [movFname,movPname]=uigetfile("*.mp4",...
                        sprintf("Open video %u",i));
                        if movFname==0
                            return
                        end
                    end
    
                    app.VidObj{i}=VideoReader(fullfile(movPname,movFname));
    
                    % Check if we have the correct video
                    if ~contains(app.projData.video.files{i},...
                            extractBefore(app.VidObj{i}.Name,...
                            length(app.VidObj{i}.Name)-3))
                        vidErrMsg={sprintf(strcat("Imported video %u ",...
                            "has a different name than ",...
                            "the DLTdv8 video."),i)};
                        vidErrMsg=[vidErrMsg,...
                            sprintf('DLTdv8 video %u is %s.',i,...
                            app.projData.video.files{i})];
                        vidErrMsg=[vidErrMsg,...
                            sprintf('You chose %s. Exiting',...
                            app.VidObj{i}.Name)];
                        uiwait(msgbox(vidErrMsg,...
                            sprintf("Wrong video %u",i),"modal"));
                        return
                    end
                end
    
                % Create video data variables for later analyses
                app.nFrames=size(app.projData.data.xypts,1); %n frames in video
                app.nVids=app.projData.data.nvid;
                app.framerate=app.projData.data.movFrameRates(1);
                app.kmtcs.time=((1:app.nFrames)/app.framerate)'; % Create time vector
                app.offset=app.projData.video.offset; %Set the videos offsets
    
                % Update other toolbars
                set(app.VidNum,'Enable','on','Value',1,'Limits',[1 app.nVids]); % Video number spinner
                set(app.FrameSlider,... % Frame slider settings
                    'Enable','on',...
                    'Limits',[1 app.nFrames],...
                    'value',app.currFrame)
                set(app.FrmDisp,'Enable','on','Value',num2str(app.currFrame)); % Frame display
                app.setOffset.Enable=1; app.CalcSumm.Enable=1;
                app.PosDisp.Enable=1;
                app.VelDisp.Enable=1; app.AccelDisp.Enable=1;
                app.AngleDisp.Enable=1; 
    
                % ###################
                % KINEMATICS ANALYSIS 
                % ###################
    
                % 1. Extract points
    
                % Point 1: Launching substrate [moving] 
                % Point 2: Landing branch [moving]
                % Point 3: Left hand [moving]
                % Point 4: Right hand [moving]
                % Point 5: Left foot [moving]
                % Point 6: Right foot [moving]
                % Point 7: Base of neck [moving]
                % Point 8: Shoulder [moving]
                % Point 9: Hip [moving]
                % Point 10: Base of tail [moving]
                % Point 11: Mid-tail [moving]
                % Point 12: Tip of tail [moving]
                % Point 13: Take-off [time point]
                % Point 14: Landing start [time point]
                % Point 15: Push-off start [time point]
                % Point 16: Landing end [time point]

                xypts=full(app.projData.data.xypts);
                xyzpts=full(app.projData.data.dltpts);
    
                if size(xypts,2)<app.nVids*2*16 
                   PtsMsg={'There appear to be missing points in this video.',...
                       sprintf(strcat('I expected %u points, but only ',...
                       'found %u in the selected DLTdv8a file.'), ...
                       app.nVids*2*16,size(xypts,2)),...
                       'Do you wish to continue or abort?'};
                   PtsAns=questdlg(PtsMsg,'Too few points', ...
                       'Continue','Abort','Continue');
                   if strcmp(PtsAns,'Abort')
                       return
                   end
                end

                posRaw=xyzpts(:,1:36); 
                evtPoints=xypts(:,1+app.nVids*2*(12:15));
                posRaw(posRaw==0)=NaN;
                app.firstFrame=find(sum(posRaw,2,'omitnan')~=0,1,'first');
                app.lastFrame=find(sum(posRaw,2,'omitnan')~=0,1,'last');

                % 2. Extract take-off and landing points
                app.leapEvents{1}=find(evtPoints(:,1)~=0); % Take-off point(s)
                if isempty(app.leapEvents{1})
                    clearuiwait(msgbox('Missing take-off point (point 13). Summary metrics may not be calculated.',...
                        'No take-off point','warn','modal'))
                end
    
                app.leapEvents{2}=find(evtPoints(:,2)~=0); % Landing start point(s)
                if isempty(app.leapEvents{2})
                    uiwait(msgbox('Missing start of landing point (point 14). Summary metrics may not be calculated.',...
                        'No start landing point','warn','modal'))
                end

                app.leapEvents{3}=find(evtPoints(:,3)~=0); % Start of push-off
                if isempty(app.leapEvents{3})
                    uiwait(msgbox('Missing the start of push-off point (point 15). Summary metrics may not be calculated.',...
                        'No start of push-off point','warn','modal'))
                end

                app.leapEvents{4}=find(evtPoints(:,4)~=0); % Landing start point(s)
                if isempty(app.leapEvents{2})
                    uiwait(msgbox('Missing end of landing point (point 16). Summary metrics may not be calculated.',...
                        'No end landing point','warn','modal'))
                end
    
                % 3. Smooth/interpolate, calculate velocities and accelerations
                primatePts=mean(reshape(posRaw(:,19:30),[],3,4),3,...
                    "omitmissing");

                totalLeapDist=norm(primatePts(app.leapEvents{2},:)-...
                    primatePts(app.leapEvents{1},:));
                primatePtsXY=nan(app.nFrames,2,app.nVids);

                for j=1:app.nVids
                    primatePtsXY(:,:,j)=...
                        app.dlt_inverse(app.projData.data.dltcoef(:,j),...
                    primatePts);
                end

                totalLeapDistPix=...
                    mean(vecnorm(primatePtsXY(app.leapEvents{2},:,:)-...
                    primatePtsXY(app.leapEvents{1},:,:),2,3));

                % Set smoothing factor based on average cm/pixel
                % calibration - triple smoothing for y-direction based
                % on reconstruction accuracy
                smthFctr=[totalLeapDist*100/totalLeapDistPix*3;
                    totalLeapDist*100/totalLeapDistPix*6;
                    totalLeapDist*100/totalLeapDistPix*3];

                [posFilt,velFilt,accelFilt]=deal(nan(size(posRaw)));
                nKmtcPts=size(posRaw,2);

                for j=1:nKmtcPts
                    NotNaN=~isnan(posRaw(:,j));
                    if sum(NotNaN)<3
                        posFilt(:,j)=posRaw(:,j);
                        continue
                    end

                    % find smoothing factor
                    xyzMod=mod(j,3); xyzMod(xyzMod==0)=3;
                    tempSmthFctr=smthFctr(xyzMod);

                    % Fit the spline
                    FirstPt=find(~isnan(posRaw(:,j)),1,'first');
                    LastPt=find(~isnan(posRaw(:,j)),1,'last');

                    spl=spaps(app.kmtcs.time(NotNaN),posRaw(NotNaN,j)*100, ...
                        tempSmthFctr,3);

                    posFilt(FirstPt:LastPt,j)=...
                        fnval(spl,app.kmtcs.time(FirstPt:LastPt))/100;
                    velFilt(FirstPt:LastPt,j)=fnval(fnder(spl,1),...
                        app.kmtcs.time(FirstPt:LastPt))/100;
                    accelFilt(FirstPt:LastPt,j)=fnval(fnder(spl,2),...
                        app.kmtcs.time(FirstPt:LastPt))/100;

                    % Put back any NaN sequences longer than 100ms (12 frames)
                    NaNst=strfind([0,isnan(posRaw(:,j)')],[0 1]);
                    NaNend=strfind([isnan(posRaw(:,j)'),0],[1 0]);
                    NaNlen=NaNend-NaNst+1;
                    tooLong=find(NaNlen>round(0.1/(1/app.framerate)));

                    for k=1:length(tooLong)
                        posFilt(NaNst(tooLong(k)):NaNend(tooLong(k)),j)=NaN;
                        velFilt(NaNst(tooLong(k)):NaNend(tooLong(k)),j)=NaN;
                        accelFilt(NaNst(tooLong(k)):NaNend(tooLong(k)),j)=NaN;
                    end
                end          
    
                % 4. Extract specific kinematic points
                    % 4A. Positions
                app.kmtcs.launch.pos.X=posFilt(:,1); % Launching substrate X-position
                app.kmtcs.launch.pos.Y=posFilt(:,2); % Launching substrate Y-position
                app.kmtcs.launch.pos.Z=posFilt(:,3); % Launching substrate Z-position
    
                app.kmtcs.land.pos.X=posFilt(:,4); % Landing substrate X-position
                app.kmtcs.land.pos.Y=posFilt(:,5); % Landing substrate Y-position
                app.kmtcs.land.pos.Z=posFilt(:,6); % Landing substrate Z-position
    
                app.kmtcs.CoM.pos.X=mean(posFilt(:,19:3:28),2); % Primate "center of mass" X-position
                app.kmtcs.CoM.pos.Y=mean(posFilt(:,20:3:29),2); % Primate "center of mass" Y-position
                app.kmtcs.CoM.pos.Z=mean(posFilt(:,21:3:30),2); % Primate "center of mass" Z-position
    
                    % 4B. Velocities
                app.kmtcs.launch.vel.X=velFilt(:,1); % Launching substrate X-velocity
                app.kmtcs.launch.vel.Y=velFilt(:,2); % Launching substrate Y-velocity
                app.kmtcs.launch.vel.Z=velFilt(:,3); % Launching substrate Z-velocity
    
                app.kmtcs.land.vel.X=velFilt(:,4); % Landing substrate X-velocity
                app.kmtcs.land.vel.Y=velFilt(:,5); % Landing substrate Y-velocity
                app.kmtcs.land.vel.Z=velFilt(:,6); % Landing substrate Z-velocity
    
                app.kmtcs.CoM.vel.X=mean(velFilt(:,19:3:28),2); % Primate "center of mass" X-velocity
                app.kmtcs.CoM.vel.Y=mean(velFilt(:,20:3:29),2); % Primate "center of mass" Y-velocity
                app.kmtcs.CoM.vel.Z=mean(velFilt(:,21:3:30),2); % Primate "center of mass" Z-velocity
    
                    % 4C. Accelerations
                app.kmtcs.launch.accel.X=accelFilt(:,1); % Launching substrate X-acceleration
                app.kmtcs.launch.accel.Y=accelFilt(:,2); % Launching substrate Y-acceleration
                app.kmtcs.launch.accel.Z=accelFilt(:,3); % Launching substrate Z-acceleration
    
                app.kmtcs.land.accel.X=accelFilt(:,4); % Landing substrate X-acceleration
                app.kmtcs.land.accel.Y=accelFilt(:,5); % Landing substrate Y-acceleration
                app.kmtcs.land.accel.Z=accelFilt(:,6); % Landing substrate Z-acceleration
    
                app.kmtcs.CoM.accel.X=mean(accelFilt(:,19:3:28),2); % Primate "center of mass" X-acceleration
                app.kmtcs.CoM.accel.Y=mean(accelFilt(:,20:3:29),2); % Primate "center of mass" Y-acceleration
                app.kmtcs.CoM.accel.Z=mean(accelFilt(:,21:3:30),2); % Primate "center of mass" Z-acceleration
    
                % 5. Calculate angles
                app.kmtcs.angles.attack=... % Angle of attack, relative to gravity
                    app.PlanarAngle(posFilt(:,28:30)-posFilt(:,19:21),[0 0 1],'deg');
                app.kmtcs.angles.tailMid=... % Tail flexion (midpoint) angle
                    app.PlanarAngle(posFilt(:,28:30)-posFilt(:,31:33),...
                    posFilt(:,34:36)-posFilt(:,31:33),'deg');
                app.kmtcs.angles.tailOverall=... % Overall tail angle, relative to trunk vector
                    app.PlanarAngle(posFilt(:,34:36)-posFilt(:,28:30),...
                    posFilt(:,19:21)-posFilt(:,28:30),'deg');
    
                % 6. Change title of data plot to let user know data are
                % analyzed
                set(app.DataPlot.Title,...
                    "String","Kinematics data analyzed. Ready to display.", ...
                    "Color","#4169E1");
    
                % 7. Update video to first frame of analyzed data
                app.currFrame=app.firstFrame;
                app.FrameSlider.Value=app.firstFrame;
                app.FrmDisp.Value=num2str(app.firstFrame);
                PaintFrame(app);

                % 8. Set app.dataLoaded to 1 and change what load data
                % button says
                app.dataLoaded=1;
                app.LoadData.Text='Load a new file';
                app.LoadData.FontColor="#1F51FF";
            else
                app.dataLoaded=0;
                app.LoadData.Text='Load DLTdv8a file';
                app.LoadData.FontColor="k";
                cla(app.DataPlot);
                cla(app.VidAxes);

                app.projData=[]; % structure storing the data exported from DLTdv8a
                app.projPname=""; % directory with DLT data (useful for saving exported data)
                app.nVids=[]; % number of videos in the dataset
                app.VidObj={}; % Cell array to store video objects
                app.currFrame=1; % Current video frame
                app.nFrames=[]; % Number of frames in video
                app.offset={}; % Video offsets (relative to video 1)
                app.framerate=[]; % Video framerate
                app.time=[]; % time vector for video and data
                app.currVideo=1; % Current video number
                app.leapEvents={}; % Leaping events (take-off, start of landing, push-off, end of landing)
                app.kmtcs=struct('time',[], ... % Large pre-allocated structure to hold kinematics variables
                    'launch',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
                    'land',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
                    'CoM',struct('pos',struct('X',[],'Y',[],'Z',[]),'vel',struct('X',[],'Y',[],'Z',[]),'accel',struct('X',[],'Y',[],'Z',[])), ...
                    'angles',struct('attack',[],'abduction',[],'tailMid',[],'tailOverall',[]));
                app.kmtcs2D=struct('time',[],'CoM2d',[],'CoM2dvel',[],'CoM2dacc',[],'launch2d',[],'land2d',[]);
                app.firstFrame=1; % First frame in which we have digitized data
                app.exportVars=struct('Species','','BodyMass',NaN,'BodyWght',NaN,...
                    'toVel',NaN,'toAng',NaN,'toMnAccel',NaN,'toMnForce',NaN,...
                    'toMnForceBW',NaN,'toMaxAccel',NaN,'toMaxForce',NaN,...
                    'toMaxForceBW',NaN,'maxHght',NaN,'netHght',NaN,'totalDist',NaN,'slDist',...
                    NaN,'MnLeapVel',NaN,'ldVel',NaN,'ldMnAccel',NaN,'ldMnForce',...
                    NaN,'ldMnForceBW',NaN,'ldMaxAccel',NaN,'ldMaxForce',NaN,...
                    'ldMaxForceBW',NaN,'launchBranchDisp',NaN,'landBranchDisp',...
                    NaN,'MinAngAttack',NaN,'MeanAngAttack',NaN,'MaxAngAttack',...
                    NaN,'MinTailTrunk',NaN,'MeanTailTrunk',NaN,'MaxTailTrunk',...
                    NaN,'MinTailMid',NaN,'MeanTailMid',NaN,'MaxTailMid',NaN);
                app.metadata=struct('SbstDiam',NaN,'SbstOrient',NaN,'SbstComp',NaN,...
                    'SbstHght',NaN,'TreeSpecies',"");

                app.SbstDiam.Value=0; app.SbstOrient.Value=0;
                app.SbstHght.Value=0; app.SbstComp.Value=0;
                app.TreeSpecies.Value=""; app.Species.Value="--"; 
                app.PosDisp.Value="--"; app.VelDisp.Value="--";
                app.AccelDisp.Value="--"; app.AngleDisp.Value="--";

                app.LoadDataButtonPushed(event);
            end
        end

        % Button pushed function: ExportData
        function ExportDataButtonPushed(app, event)

            % 1. Get data save location from user
            saveDir=uigetdir(app.projPname,...
                "Where do you want to save the data?");

            % 2. Create a save file
            filename=strcat(strrep(app.WildLeap3DUIFigure.Name,...
                "WildLeap3D | ",""),"_SummaryVars.csv");
            SVsaveFile=fullfile(saveDir,filename);

            % 2. Create a table of the final exported variables, and add
            % all necessary metadata
            SVsaveTable=struct2table(app.exportVars);
            SVsaveTable.SbstDiam=app.metadata.SbstDiam;
            SVsaveTable.SbstOrient=app.metadata.SbstOrient;
            SVsaveTable.SbstComp=app.metadata.SbstComp;
            SVsaveTable.SbstHght=app.metadata.SbstHght;
            SVsaveTable.TreeSpecies=app.metadata.TreeSpecies;
            SVsaveTable=movevars(SVsaveTable,...
                {'SbstDiam','SbstOrient','SbstComp','SbstHght','TreeSpecies'},...
                "After","BodyWght");
            
            %3. Write the final export table to a CSV
            writetable(SVsaveTable,SVsaveFile,"FileType","text",...
                "Delimiter",",")

             %4. Export time series variables as CSV
             ldSt=app.leapEvents{2}(1);
             ldEnd=app.leapEvents{4}(1);
             poStart=app.leapEvents{3}(); 
             to=app.leapEvents{1}()-poStart; 

             % 4a. Save 2D (planar) kinematic data from the jump
             kmtcs2DTable=table();

             kmtcs2DTable.time=...
                 app.kmtcs.time(poStart:ldSt)-app.kmtcs.time(poStart);
             kmtcs2DTable.COMposX=app.kmtcs2D.CoM2d(1:(ldSt-poStart+1),1);
             kmtcs2DTable.COMposY=app.kmtcs2D.CoM2d(1:(ldSt-poStart+1),2);
             kmtcs2DTable.COMvelX=app.kmtcs2D.CoM2dvel(:,1);
             kmtcs2DTable.COMvelY=app.kmtcs2D.CoM2dvel(:,2);
             kmtcs2DTable.COMaccX=app.kmtcs2D.CoM2dacc(:,1);
             kmtcs2DTable.COMaccY=app.kmtcs2D.CoM2dacc(:,2);
             kmtcs2DTable.takeoff=to.*ones(size(kmtcs2DTable,1),1);

             kmtcs2DSaveFile=strrep(SVsaveFile,"SummaryVars","2DTimeSeries");
             writetable(kmtcs2DTable,kmtcs2DSaveFile,"FileType","text",...
                "Delimiter",",")

            % 4a. Save 3D kinematic data and angles from the jump
             kmtcsTable=table();

             kmtcsTable.time=...
                 app.kmtcs.time(app.firstFrame:app.lastFrame)-...
                 app.kmtcs.time(app.firstFrame);

             launchPosTbl=struct2table(app.kmtcs.launch.pos);
             launchPosTbl=launchPosTbl(app.firstFrame:app.lastFrame,:);
             launchPosTbl.Properties.VariableNames=...
                 strcat("launchPos",launchPosTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,launchPosTbl];

             launchVelTbl=struct2table(app.kmtcs.launch.vel);
             launchVelTbl=launchVelTbl(app.firstFrame:app.lastFrame,:);
             launchVelTbl.Properties.VariableNames=...
                 strcat("launchVel",launchVelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,launchVelTbl];

             launchAccelTbl=struct2table(app.kmtcs.launch.accel);
             launchAccelTbl=launchAccelTbl(app.firstFrame:app.lastFrame,:);
             launchAccelTbl.Properties.VariableNames=...
                 strcat("launchAccel",launchAccelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,launchAccelTbl];

             landPosTbl=struct2table(app.kmtcs.land.pos);
             landPosTbl=landPosTbl(app.firstFrame:app.lastFrame,:);
             landPosTbl.Properties.VariableNames=...
                 strcat("landPos",landPosTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,landPosTbl];

             landVelTbl=struct2table(app.kmtcs.land.vel);
             landVelTbl=landVelTbl(app.firstFrame:app.lastFrame,:);
             landVelTbl.Properties.VariableNames=...
                 strcat("landVel",landVelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,landVelTbl];

             landAccelTbl=struct2table(app.kmtcs.land.accel);
             landAccelTbl=landAccelTbl(app.firstFrame:app.lastFrame,:);
             landAccelTbl.Properties.VariableNames=...
                 strcat("landAccel",landAccelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,landAccelTbl];

             CoMPosTbl=struct2table(app.kmtcs.CoM.pos);
             CoMPosTbl=CoMPosTbl(app.firstFrame:app.lastFrame,:);
             CoMPosTbl.Properties.VariableNames=...
                 strcat("CoMPos",CoMPosTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,CoMPosTbl];

             CoMVelTbl=struct2table(app.kmtcs.CoM.vel);
             CoMVelTbl=CoMVelTbl(app.firstFrame:app.lastFrame,:);
             CoMVelTbl.Properties.VariableNames=...
                 strcat("CoMVel",CoMVelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,CoMVelTbl];

             CoMAccelTbl=struct2table(app.kmtcs.CoM.accel);
             CoMAccelTbl=CoMAccelTbl(app.firstFrame:app.lastFrame,:);
             CoMAccelTbl.Properties.VariableNames=...
                 strcat("CoMAccel",CoMAccelTbl.Properties.VariableNames);
             kmtcsTable=[kmtcsTable,CoMAccelTbl];

             AnglesFN=fieldnames(app.kmtcs.angles);
             tf=cellfun(@(c) isempty(app.kmtcs.angles.(c)), AnglesFN);
             CleanAngles=rmfield(app.kmtcs.angles, AnglesFN(tf));
             anglesTbl=struct2table(CleanAngles);
             anglesTbl=anglesTbl(app.firstFrame:app.lastFrame,:);
             anglesTbl.Properties.VariableNames=...
                 strcat(anglesTbl.Properties.VariableNames,"Angle");
             kmtcsTable=[kmtcsTable,anglesTbl];

             % attach leap events
             leapEvtNames={'takeoff','landStart','POstart','landEnd'};
             for j=1:length(app.leapEvents)
                 tempEventFrm=app.leapEvents{j}-app.firstFrame;
                 tempEventTable=table(...
                     ones(size(kmtcsTable,1),1)*tempEventFrm,...
                     'VariableNames',leapEvtNames(j));
                kmtcsTable=[kmtcsTable,tempEventTable];
             end

             kmtcsSaveFile=strrep(SVsaveFile,"SummaryVars","FullTimeSeries");
             writetable(kmtcsTable,kmtcsSaveFile,"FileType","text",...
                "Delimiter",",")

             % 5. Let user know data are saved
             uiwait(msgbox('Data saved.','Data saved','modal'));
        end

        % Value changed function: AngleDisp
        function AngleDispValueChanged(app, event)
            value = app.AngleDisp.Value;
            to=app.leapEvents{1}; ld=app.leapEvents{2};
            
            cla(app.DataPlot)
            switch value
                case '--'
                    app.DataPlot.Title.String=...
                        "Kinematics data analyzed. Ready to display.";
                case 'Angle of attack'
                    app.plotResults(app.kmtcs.time,...
                        app.kmtcs.angles.attack, ...
                        app.DataPlot,to,ld,...
                        "Primate angle of attack (relative to gravity)",...
                        "Angle");
                case 'Mid-tail angle'
                    app.plotResults(app.kmtcs.time,...
                        app.kmtcs.angles.tailMid, ...
                        app.DataPlot,to,ld,...
                        "Mid-tail angle","Angle");
                case 'Tail-trunk angle'
                    app.plotResults(app.kmtcs.time,...
                        app.kmtcs.angles.tailOverall, ...
                        app.DataPlot,to,ld,...
                        "Mid-tail angle","Angle");
            end

            app.PosDisp.Value="--";
            app.VelDisp.Value="--";
            app.AccelDisp.Value="--";

        end

        % Key press function: WildLeap3DUIFigure
        function WildLeap3DUIFigureKeyPress(app, event)
            key = event.Key;
            switch(key)
                case {'leftarrow','b'}
                    app.FrameSlider.Value=app.FrameSlider.Value-1;
                    app.FrameSliderValueChanged(event);
                case {'rightarrow','f'}
                    app.FrameSlider.Value=app.FrameSlider.Value+1;
                    app.FrameSliderValueChanged(event);
            end
        end

        % Button pushed function: CalcSumm
        function CalcSummButtonPushed(app, event)
            % ############################
            % # PRIMATE COM CALCULATIONS #
            % ############################

            % These analyses replicate those of Janisch et al. (2021)
            % Janisch J, Perinot E, Fusani L, and Quigley C. 2021. 
            % Deciphering choreographies of elaborate courtship displays 
            % of golden‐collared manakins using markerless motion capture. 
            % Ethology 127(7):550-562.

            % 1. Project primate and branch coordinates onto 2D plane and 
            % find best fit parabola

            % a. pull out 3d coordinates of CoM during leap
            to=app.leapEvents{1}(); ldSt=app.leapEvents{2}(1);
            poStart=app.leapEvents{3}(); ldEnd=app.leapEvents{4}(1);
            CoM3d=reshape(cell2mat(struct2cell(app.kmtcs.CoM.pos)),[],3);
            CoM3dleap=CoM3d(poStart:ldSt,:)-CoM3d(poStart,:); % create point matrix just during ballistic phase of leap

            % b. get 2d CoM coordinates on plane defined by z axis and 
            % take-off to landing straight-line vector (based on method in 
            % Janisch et al. 2021)
            [~,projMat]=app.projectToPlane(CoM3dleap,... % use vector from take-off to start of landing for projMat
                CoM3dleap(1,:),CoM3dleap(end,:));
            CoM3d=CoM3d(poStart:ldEnd,:)-CoM3d(poStart,:); % create point matrix of entire leap
            app.kmtcs2D.CoM2d=(projMat*CoM3d')'; % Entire leap used for distance calculations

            % Get rotated velocities and accelerations
            CoM3dvel=reshape(cell2mat(struct2cell(app.kmtcs.CoM.vel)),[],3);
            CoM3dvel=CoM3dvel(poStart:ldSt,:); 
            app.kmtcs2D.CoM2dvel=(projMat*CoM3dvel')';

            CoM3dacc=reshape(cell2mat(struct2cell(app.kmtcs.CoM.accel)),[],3);
            CoM3dacc=CoM3dacc(poStart:ldSt,:); 
            app.kmtcs2D.CoM2dacc=(projMat*CoM3dacc')';

            % c. best fit parabola to CoM 2d trajectories
            leapPolyFit=polyfit(app.kmtcs2D.CoM2d(:,1),app.kmtcs2D.CoM2d(:,2),2);

            % d. apply planar transformation to launching branch
            launch3d=reshape(cell2mat(struct2cell(app.kmtcs.launch.pos)),[],3);
            launch3d=launch3d(poStart:to,:)-launch3d(poStart,:); % create point matrix just during leap
            launch2d=(projMat*launch3d')'; 
            app.kmtcs2D.launch2d=launch2d;
            
            % e. apply planar transformation to landing branch
            land3d=reshape(cell2mat(struct2cell(app.kmtcs.land.pos)),[],3);
            land3d=land3d(ldSt:end,:)-land3d(ldSt,:); % create point matrix just during leap
            land2d=(projMat*land3d')';
            app.kmtcs2D.land2d=land2d;

            % 2. Get body mass (in grams) based on species
            app.exportVars.Species=app.Species.Value;
            
            switch app.exportVars.Species
                case('--')
                    uiwait(msgbox('Species not selected. Aborting.','Select Species','modal'));
                    return
                case('Propithecus edwardsi')
                    app.exportVars.BodyMass=mean([5469 5895]); % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Eulemur rubriventer')
                    app.exportVars.BodyMass=mean([1980 1940]); % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Eulemur rufifrons')
                    app.exportVars.BodyMass=2220; % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Hapalemur aureus')
                    app.exportVars.BodyMass=1460; % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Lemur catta')
                    app.exportVars.BodyMass=2210; % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Piliocolobus tephrosceles')
                    app.exportVars.BodyMass=8370; % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Colobus guereza')
                    app.exportVars.BodyMass=mean([13500 9200]); % from Primate Adaptation and Evolution; Fleagle (2013)
                case('Cercopithecus ascanius')
                    app.exportVars.BodyMass=mean([3700 2920]); % from Primate Adaptation and Evolution; Fleagle (2013)                    
            end
            app.exportVars.BodyWght=(app.exportVars.BodyMass/1000)*9.81; % Body weight in N

            % 3. Calculate outcome variables - NOTE: y-axis accelerations
            % not included due to remaining digitizing/reconstruction error
            % resulting in acceleratoins that are too high

                % a. Take-off velocity
            app.exportVars.toVel=sqrt(sum([app.kmtcs.CoM.vel.X(to)^2,...
                app.kmtcs.CoM.vel.Y(to)^2,...
                app.kmtcs.CoM.vel.Z(to)^2]));

                % b. Take-off angle
            app.exportVars.toAng=atand(leapPolyFit(2));

                % c. Mean take-off acceleration
            app.exportVars.toMnAccel=...
                mean(sum([app.kmtcs.CoM.accel.X(poStart:to).^2,...
                app.kmtcs.CoM.accel.Y(poStart:to).^2,...
                app.kmtcs.CoM.accel.Z(poStart:to).^2],2).^0.5,"omitnan");

                % d. Mean take-off force
            app.exportVars.toMnForce=app.exportVars.toMnAccel*...
                (app.exportVars.BodyMass/1000);
            app.exportVars.toMnForceBW=app.exportVars.toMnForce/...
                app.exportVars.BodyWght;

                % c. Max take-off acceleration
            app.exportVars.toMaxAccel=...
                max(sum([app.kmtcs.CoM.accel.X(poStart:to).^2,...
                app.kmtcs.CoM.accel.Y(poStart:to).^2,...
                app.kmtcs.CoM.accel.Z(poStart:to).^2],2).^0.5,[],...
                "omitnan");

                % d. Max take-off force
            app.exportVars.toMaxForce=app.exportVars.toMaxAccel*...
                (app.exportVars.BodyMass/1000);
            app.exportVars.toMaxForceBW=app.exportVars.toMaxForce/...
                app.exportVars.BodyWght;

                % e. Net/Max height during leap (above height at take-off)
            app.exportVars.maxHght=max(app.kmtcs2D.CoM2d(:,2));
            app.exportVars.netHght=...
                app.kmtcs2D.CoM2d(end,2)-(app.kmtcs2D.CoM2d(1,2));

                % f. Parabolic (total) distance
            app.exportVars.totalDist=app.arclength(app.kmtcs2D.CoM2d(:,1),...
                app.kmtcs2D.CoM2d(:,2),'spline');

                % g. Straight line distance
            app.exportVars.slDist=norm(app.kmtcs2D.CoM2d(end,:)...
                -app.kmtcs2D.CoM2d(1,:));

                % h. Average speed during leap
            app.exportVars.MnLeapVel=mean(sum([app.kmtcs.CoM.vel.X(to:ldSt).^2,...
                app.kmtcs.CoM.vel.Y(to:ldSt).^2,...
                app.kmtcs.CoM.vel.Z(to:ldSt).^2],2).^0.5,"omitnan");

                % g. Landing speed
            app.exportVars.ldVel=sqrt(sum([app.kmtcs.CoM.vel.X(ldSt)^2,...
                app.kmtcs.CoM.vel.Y(ldSt)^2,...
                app.kmtcs.CoM.vel.Z(ldSt)^2]));

                % h. Mean landing acceleration
            app.exportVars.ldMnAccel=mean(sum([app.kmtcs.CoM.accel.X(ldSt:end).^2,...
                app.kmtcs.CoM.accel.Y(ldSt:end).^2,...
                app.kmtcs.CoM.accel.Z(ldSt:end).^2],2).^0.5,"omitnan");

                % i. Mean landing force
            app.exportVars.ldMnForce=app.exportVars.ldMnAccel*...
                (app.exportVars.BodyMass/1000);
            app.exportVars.ldMnForceBW=app.exportVars.ldMnForce/...
                app.exportVars.BodyWght;

                % j. Max landing acceleration
            app.exportVars.ldMaxAccel=max(sum([app.kmtcs.CoM.accel.X(ldSt:end).^2,...
                app.kmtcs.CoM.accel.Y(ldSt:end).^2,...
                app.kmtcs.CoM.accel.Z(ldSt:end).^2],2).^0.5,[],...
                "omitnan");

                % k. Max landing force
            app.exportVars.ldMaxForce=app.exportVars.ldMaxAccel*...
                (app.exportVars.BodyMass/1000);
            app.exportVars.ldMaxForceBW=app.exportVars.ldMaxForce/...
                app.exportVars.BodyWght;

                % j. Launch branch displacement during take-off
            if ~all(isnan(launch2d),"all")
                app.exportVars.launchBranchDisp=...
                    app.arclength(launch2d(~isnan(sum(launch2d,2)),1),...
                    launch2d(~isnan(sum(launch2d,2)),2),'spline');
            end

                % k. Landing branch displacement during landing
            if ~all(isnan(land2d),"all")
                app.exportVars.landBranchDisp=...
                    app.arclength(land2d(~isnan(sum(land2d,2)),1),...
                    land2d(~isnan(sum(land2d,2)),2),'spline');
            end

                % l. Angle of attack
            app.exportVars.MinAngAttack=...
                min(app.kmtcs.angles.attack,[],"omitnan");
            app.exportVars.MeanAngAttack=...
                mean(app.kmtcs.angles.attack,"omitnan");
            app.exportVars.MaxAngAttack=...
                max(app.kmtcs.angles.attack,[],"omitnan");

                % m. Overall tail angle
            app.exportVars.MinTailTrunk=...
                min(app.kmtcs.angles.tailOverall,[],"omitnan");
            app.exportVars.MeanTailTrunk=...
                mean(app.kmtcs.angles.tailOverall,"omitnan");
            app.exportVars.MaxTailTrunk=...
                max(app.kmtcs.angles.tailOverall,[],"omitnan");

                % n. Mid-tail angle
            app.exportVars.MinTailMid=...
                min(app.kmtcs.angles.tailMid,[],"omitnan");
            app.exportVars.MeanTailMid=...
                mean(app.kmtcs.angles.tailMid,"omitnan");
            app.exportVars.MaxTailMid=...
                max(app.kmtcs.angles.tailMid,[],"omitnan");

            % 4. Display outcome variables in Data Plot

                % a. set figure properties for displaying text
            cla(app.DataPlot); app.DataPlot.View=[0 90];
            set(app.DataPlot,"Units","characters","XGrid","off",...
                "YGrid","off");
            nlines=app.DataPlot.Position; nlines=floor(nlines(4));
            textLineInc=max(app.DataPlot.YLim)/nlines*1.25;
            leftMargin=min(app.DataPlot.XLim)+diff(app.DataPlot.XLim)*0.25;
            currLine=max(app.DataPlot.YLim)-textLineInc;

                % b. Loop through to display summary metrics
            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Take-off velocity: %4.2f m/sec',...
                app.exportVars.toVel),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Take-off angle: %4.2f deg',...
                app.exportVars.toAng),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                "","FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Max push-off acceleration: %4.2f m/sec^2',...
                app.exportVars.toMaxAccel),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Rel Max push-off force: %4.2f BW',...
                app.exportVars.toMaxForceBW),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                "","FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Max height gain: %4.2f m',...
                app.exportVars.maxHght),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Total distance traveled: %4.2f m',...
                app.exportVars.totalDist),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Average speed during leap: %4.2f m/sec',...
                app.exportVars.MnLeapVel),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                "","FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Landing speed: %4.2f m/sec',...
                app.exportVars.ldVel),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Max landing acceleration: %4.2f m/sec^2',...
                app.exportVars.ldMaxAccel),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Rel max landing force: %4.2f BW',...
                app.exportVars.ldMaxForceBW),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                "","FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Launching branch displacement: %4.2f m',...
                app.exportVars.launchBranchDisp),"FontSize",12); 
            currLine=currLine-textLineInc;
            
            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Landing branch displacement: %4.2f m',...
                app.exportVars.landBranchDisp),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                "","FontSize",12); 
            currLine=currLine-textLineInc;
            
            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Mean angle of attack: %4.2f deg',...
                app.exportVars.MeanAngAttack),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Mean tail-trunk angle: %4.2f deg',...
                app.exportVars.MeanTailTrunk),"FontSize",12); 
            currLine=currLine-textLineInc;

            text(app.DataPlot,leftMargin,currLine,...
                sprintf('Mean mid-tail angle: %4.2f deg',...
                app.exportVars.MeanTailMid),"FontSize",12); 

            % 5. reset figure properties to default
            set(app.DataPlot.Title,...
                "String","Summary Kinematic Data", ...
                "Color","#D22B2B")
            app.ExportData.Enable=1; 
        end

        % Value changed function: SbstDiam
        function SbstDiamValueChanged(app, event)
            value = app.SbstDiam.Value;
            app.metadata.SbstDiam=value;
        end

        % Value changed function: SbstOrient
        function SbstOrientValueChanged(app, event)
            value = app.SbstOrient.Value;
            app.metadata.SbstOrient=value;

        end

        % Value changed function: SbstComp
        function SbstCompValueChanged(app, event)
            value = app.SbstComp.Value;
            app.metadata.SbstComp=value;
        end

        % Value changed function: SbstHght
        function SbstHghtValueChanged(app, event)
            value = app.SbstHght.Value;
            app.metadata.SbstHght=value;            
        end

        % Value changed function: TreeSpecies
        function TreeSpeciesValueChanged(app, event)
            value = app.TreeSpecies.Value;
            app.metadata.TreeSpecies=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WildLeap3DUIFigure = uifigure('Visible', 'off');
            colormap(app.WildLeap3DUIFigure, 'jet');
            app.WildLeap3DUIFigure.Name = 'WildLeap3D';
            app.WildLeap3DUIFigure.Icon = fullfile(pathToMLAPP, '593x1024.png');
            app.WildLeap3DUIFigure.KeyPressFcn = createCallbackFcn(app, @WildLeap3DUIFigureKeyPress, true);
            app.WildLeap3DUIFigure.Tag = 'WildLeap3D';
            app.DataPlot = uiaxes(app.WildLeap3DUIFigure);
            title(app.DataPlot, 'Processed data will appear here...')
            xlabel(app.DataPlot, 'X')
            ylabel(app.DataPlot, 'Y')
            zlabel(app.DataPlot, 'Z')
            app.DataPlot.ButtonDownFcn = createCallbackFcn(app, @DataPlotButtonDown, true);
            app.VidAxes = uiaxes(app.WildLeap3DUIFigure);
            xlabel(app.VidAxes, 'X')
            ylabel(app.VidAxes, 'Y')
            zlabel(app.VidAxes, 'Z')
            app.VidAxes.XLimitMethod = 'tight';
            app.VidAxes.YLimitMethod = 'tight';
            app.VidAxes.ZLimitMethod = 'tight';
            app.VidAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.VidAxes.Tag = 'VidAxes';
            app.FrameSliderLabel = uilabel(app.WildLeap3DUIFigure);
            app.FrameSliderLabel.Text = 'Frame #';
            app.FrameSlider = uislider(app.WildLeap3DUIFigure);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.MajorTickLabels = {''};
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Tag = 'FrameSlider';
            app.FrameSlider.Enable = 'off';
            app.VidNumSpinnerLabel = uilabel(app.WildLeap3DUIFigure);
            app.VidNumSpinnerLabel.Text = 'Vid Num';
            app.VidNum = uispinner(app.WildLeap3DUIFigure);
            app.VidNum.Limits = [1 Inf];
            app.VidNum.ValueChangedFcn = createCallbackFcn(app, @VidNumValueChanged, true);
            app.VidNum.Tag = 'VidNum';
            app.VidNum.Enable = 'off';
            app.VidNum.Value = 1;
            app.CamoffsetreltoCam1EditFieldLabel = uilabel(app.WildLeap3DUIFigure);
            app.CamoffsetreltoCam1EditFieldLabel.Text = 'Cam offset (rel to Cam 1)';
            app.setOffset = uieditfield(app.WildLeap3DUIFigure, 'numeric');
            app.setOffset.RoundFractionalValues = 'on';
            app.setOffset.ValueDisplayFormat = '%.0f';
            app.setOffset.ValueChangedFcn = createCallbackFcn(app, @setOffsetValueChanged, true);
            app.setOffset.Tag = 'Offset';
            app.setOffset.Enable = 'off';
            app.LoadData = uibutton(app.WildLeap3DUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Load DLTdv8a file';
            app.ExportData = uibutton(app.WildLeap3DUIFigure, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportData.Enable = 'off';
            app.ExportData.Text = 'Export data';
            app.SbstOrientdegLabel = uilabel(app.WildLeap3DUIFigure);
            app.SbstOrientdegLabel.Text = 'Sbst Orient (deg)';
            app.SbstOrient = uieditfield(app.WildLeap3DUIFigure, 'numeric');
            app.SbstOrient.ValueChangedFcn = createCallbackFcn(app, @SbstOrientValueChanged, true);
            app.SbstDiamcmEditFieldLabel = uilabel(app.WildLeap3DUIFigure);
            app.SbstDiamcmEditFieldLabel.Text = 'Sbst Diam (cm)';
            app.SbstDiam = uieditfield(app.WildLeap3DUIFigure, 'numeric');
            app.SbstDiam.AllowEmpty = 'on';
            app.SbstDiam.ValueChangedFcn = createCallbackFcn(app, @SbstDiamValueChanged, true);
            app.SbstOrientdegLabel_2 = uilabel(app.WildLeap3DUIFigure);
            app.SbstOrientdegLabel_2.Text = 'Sbst Comp (cm\N)';
            app.SbstComp = uieditfield(app.WildLeap3DUIFigure, 'numeric');
            app.SbstComp.ValueChangedFcn = createCallbackFcn(app, @SbstCompValueChanged, true);
            app.SbstOrientdegLabel_3 = uilabel(app.WildLeap3DUIFigure);
            app.SbstOrientdegLabel_3.Text = 'Sbst Hght (m)';
            app.SbstHght = uieditfield(app.WildLeap3DUIFigure, 'numeric');
            app.SbstHght.ValueChangedFcn = createCallbackFcn(app, @SbstHghtValueChanged, true);
            app.TreeSpeciesEditFieldLabel = uilabel(app.WildLeap3DUIFigure);
            app.TreeSpeciesEditFieldLabel.Text = 'Tree Species';
            app.TreeSpecies = uieditfield(app.WildLeap3DUIFigure, 'text');
            app.TreeSpecies.ValueChangedFcn = createCallbackFcn(app, @TreeSpeciesValueChanged, true);
            app.PositiondataLabel = uilabel(app.WildLeap3DUIFigure);
            app.PositiondataLabel.Text = 'Positions';
            app.PosDisp = uidropdown(app.WildLeap3DUIFigure);
            app.PosDisp.Items = {'--', 'Launching substrate', 'Landing substrate', 'Primate CoM', '3D plot'};
            app.PosDisp.ValueChangedFcn = createCallbackFcn(app, @PosDispValueChanged, true);
            app.PosDisp.Enable = 'off';
            app.PosDisp.Value = '--';
            app.PositiondataLabel_2 = uilabel(app.WildLeap3DUIFigure);
            app.PositiondataLabel_2.Text = 'Velocities';
            app.VelDisp = uidropdown(app.WildLeap3DUIFigure);
            app.VelDisp.Items = {'--', 'Launching substrate', 'Landing substrate', 'Primate CoM'};
            app.VelDisp.ValueChangedFcn = createCallbackFcn(app, @VelDispValueChanged, true);
            app.VelDisp.Enable = 'off';
            app.VelDisp.Value = '--';
            app.PositiondataLabel_3 = uilabel(app.WildLeap3DUIFigure);
            app.PositiondataLabel_3.Text = 'Accelerations';
            app.AccelDisp = uidropdown(app.WildLeap3DUIFigure);
            app.AccelDisp.Items = {'--', '--', 'Launching substrate', 'Landing substrate', 'Primate CoM'};
            app.AccelDisp.ValueChangedFcn = createCallbackFcn(app, @AccelDispValueChanged, true);
            app.AccelDisp.Enable = 'off';
            app.AccelDisp.Value = '--';
            app.PositiondataLabel_4 = uilabel(app.WildLeap3DUIFigure);
            app.PositiondataLabel_4.Text = 'Angles';
            app.AngleDisp = uidropdown(app.WildLeap3DUIFigure);
            app.AngleDisp.Items = {'--', 'Angle of attack', 'Mid-tail angle', 'Tail-trunk angle'};
            app.AngleDisp.ValueChangedFcn = createCallbackFcn(app, @AngleDispValueChanged, true);
            app.AngleDisp.Enable = 'off';
            app.AngleDisp.Value = '--';
            app.CurrframeEditFieldLabel = uilabel(app.WildLeap3DUIFigure);
            app.CurrframeEditFieldLabel.Text = 'Curr frame';
            app.FrmDisp = uieditfield(app.WildLeap3DUIFigure, 'text');
            app.FrmDisp.ValueChangedFcn = createCallbackFcn(app, @FrmDispValueChanged, true);
            app.FrmDisp.Enable = 'off';
            app.FrmDisp.Value = '1';
            app.SpeciesDropDownLabel = uilabel(app.WildLeap3DUIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.Species = uidropdown(app.WildLeap3DUIFigure);
            app.Species.Items = {'--', 'Propithecus edwardsi', 'Lemur catta', 'Hapalemur aureus', 'Eulemur rufifrons', 'Eulemur rubriventer', 'Piliocolobus tephrosceles', 'Colobus guereza', 'Cercopithecus ascanius'};
            app.Species.Value = '--';
            app.CalcSumm = uibutton(app.WildLeap3DUIFigure, 'push');
            app.CalcSumm.ButtonPushedFcn = createCallbackFcn(app, @CalcSummButtonPushed, true);
            app.CalcSumm.Enable = 'off';
            app.CalcSumm.Text = 'Calculate summary metrics';
            % Show the figure after all components are created
            app.WildLeap3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WildLeap3D

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WildLeap3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.WildLeap3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WildLeap3DUIFigure)
        end
    end
end